classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RabbitsSpinnerLabel         matlab.ui.control.Label
        RabbitsSpinner              matlab.ui.control.Spinner
        WolvesSpinnerLabel          matlab.ui.control.Label
        WolvesSpinner               matlab.ui.control.Spinner
        ShewolvesSpinnerLabel       matlab.ui.control.Label
        ShewolvesSpinner            matlab.ui.control.Spinner
        map_nSliderLabel            matlab.ui.control.Label
        map_nSlider                 matlab.ui.control.Slider
        map_mSliderLabel            matlab.ui.control.Label
        map_mSlider                 matlab.ui.control.Slider
        ChanceofnewrabbitEditFieldLabel  matlab.ui.control.Label
        ChanceofnewrabbitEditField  matlab.ui.control.NumericEditField
        HungerEditFieldLabel        matlab.ui.control.Label
        HungerEditField             matlab.ui.control.NumericEditField
        FoodEditFieldLabel          matlab.ui.control.Label
        FoodEditField               matlab.ui.control.NumericEditField
        StepButton                  matlab.ui.control.Button
        CreatenewworldButton        matlab.ui.control.Button
        RunButton                   matlab.ui.control.Button
        ResetparametersButton       matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
    end


    properties (Access = public)
            n % размер карты
            m
            NRab % число кроликов
            NW % число волков
            NWf % число волчиц
            RabVer %вероятность нового кролика - 1/RabVer (0.2)
            Ha %потери здоровья от голода
            Hf %добавление здоровья от ужина
            Map %создание "острова", 1 - суша
            Map1
            Rabbit %Создание слоя кроликов 
            Wolf %Создание слоя волков 
            Wolffem %Создание слоя волчиц
            count
    end


    methods (Access = private)

        % Button pushed function: ResetparametersButton
        function ResetparametersButtonPushed(app, event)
            app.map_nSlider.Value=10;
            app.map_mSlider.Value=10;
            app.FoodEditField.Value=1;
            app.HungerEditField.Value=0.1;
            app.ChanceofnewrabbitEditField.Value=5;
            app.RabbitsSpinner.Value=10;
            app.WolvesSpinner.Value=2;
            app.ShewolvesSpinner.Value=2;
        end

        % Button pushed function: CreatenewworldButton
        function CreatenewworldButtonPushed(app, event)
            app.n=round(app.map_nSlider.Value); app.m=round(app.map_mSlider.Value); % размер карты
            app.NRab=app.RabbitsSpinner.Value; % число кроликов
            app.NW=app.WolvesSpinner.Value; % число волков
            app.NWf=app.ShewolvesSpinner.Value; % число волчиц
            app.RabVer=app.ChanceofnewrabbitEditField.Value; %вероятность нового кролика - 1/RabVer (0.2)
            app.Ha=app.HungerEditField.Value; %потери здоровья от голода
            app.Hf=app.FoodEditField.Value; %добавление здоровья от ужина

            app.Map=[zeros(1,app.m+2); zeros(app.n,1) ones(app.n,app.m) zeros(app.n,1);zeros(1,app.m+2)]; %создание "острова", 1 - суша
            app.Map(randi(round(app.n*app.m),[1 app.n+app.m]))=0;
            app.Map1=app.Map;
            app.Map1(randi(app.n*app.m,[1 max(app.n,app.m)]))=0;
            app.Rabbit=CreateM(app.Map, app.NRab); %Создание слоя кроликов 
            app.Wolf=CreateM(app.Map, app.NW); %Создание слоя волков 
            app.Wolffem=CreateM(app.Map, app.NWf); %Создание слоя волчиц
            
            figure(2);
            I=Showme(app.Map,app.Rabbit,app.Wolf,app.Wolffem); %визуализация
            imshow(I)
            app.count=1;
            %count=0;
           % app.Label=["№_" count];
        end

        % Button pushed function: StepButton
        function StepButtonPushed(app, event)
            [app.Map app.Map1 app.Rabbit app.Wolf app.Wolffem]=RabbitSTEP(app.Map,app.Map1,app.Rabbit,app.Wolf,app.Wolffem,app.RabVer,app.Hf,app.Ha);
            app.count=1;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.count=1;
            while app.count==1;
                [app.Map app.Map1 app.Rabbit app.Wolf app.Wolffem]=RabbitSTEP(app.Map,app.Map1,app.Rabbit,app.Wolf,app.Wolffem,app.RabVer,app.Hf,app.Ha);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.count=2;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RabbitsSpinnerLabel = uilabel(app.UIFigure);
            app.RabbitsSpinnerLabel.Text = 'Rabbits';
            app.RabbitsSpinner = uispinner(app.UIFigure);
            app.RabbitsSpinner.Limits = [1 100];
            app.RabbitsSpinner.Value = 10;
            app.WolvesSpinnerLabel = uilabel(app.UIFigure);
            app.WolvesSpinnerLabel.Text = 'Wolves';
            app.WolvesSpinner = uispinner(app.UIFigure);
            app.WolvesSpinner.Limits = [1 100];
            app.WolvesSpinner.Value = 2;
            app.ShewolvesSpinnerLabel = uilabel(app.UIFigure);
            app.ShewolvesSpinnerLabel.Text = 'She-wolves';
            app.ShewolvesSpinner = uispinner(app.UIFigure);
            app.ShewolvesSpinner.Limits = [1 100];
            app.ShewolvesSpinner.Value = 2;
            app.map_nSliderLabel = uilabel(app.UIFigure);
            app.map_nSliderLabel.Text = 'map_n';
            app.map_nSlider = uislider(app.UIFigure);
            app.map_nSlider.Limits = [1 20];
            app.map_nSlider.MajorTicks = [1 4 7 10 13 16 20];
            app.map_nSlider.Value = 10;
            app.map_mSliderLabel = uilabel(app.UIFigure);
            app.map_mSliderLabel.Text = 'map_m';
            app.map_mSlider = uislider(app.UIFigure);
            app.map_mSlider.Limits = [1 20];
            app.map_mSlider.MajorTicks = [1 4 7 10 13 16 20];
            app.map_mSlider.Value = 10;
            app.ChanceofnewrabbitEditFieldLabel = uilabel(app.UIFigure);
            app.ChanceofnewrabbitEditFieldLabel.Text = 'Chance of new rabbit';
            app.ChanceofnewrabbitEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChanceofnewrabbitEditField.ValueDisplayFormat = '%.0f';
            app.ChanceofnewrabbitEditField.Value = 5;
            app.HungerEditFieldLabel = uilabel(app.UIFigure);
            app.HungerEditFieldLabel.Text = 'Hunger';
            app.HungerEditField = uieditfield(app.UIFigure, 'numeric');
            app.HungerEditField.Value = 0.1;
            app.FoodEditFieldLabel = uilabel(app.UIFigure);
            app.FoodEditFieldLabel.Text = 'Food';
            app.FoodEditField = uieditfield(app.UIFigure, 'numeric');
            app.FoodEditField.Value = 1;
            app.StepButton = uibutton(app.UIFigure, 'push');
            app.StepButton.ButtonPushedFcn = createCallbackFcn(app, @StepButtonPushed, true);
            app.StepButton.Text = 'Step';
            app.CreatenewworldButton = uibutton(app.UIFigure, 'push');
            app.CreatenewworldButton.ButtonPushedFcn = createCallbackFcn(app, @CreatenewworldButtonPushed, true);
            app.CreatenewworldButton.Text = 'Create new world';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResetparametersButton = uibutton(app.UIFigure, 'push');
            app.ResetparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetparametersButtonPushed, true);
            app.ResetparametersButton.Text = 'Reset parameters';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end